classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ymaximumSlider          matlab.ui.control.Slider
        ymaximumSliderLabel     matlab.ui.control.Label
        yminimumSlider          matlab.ui.control.Slider
        yminimumSliderLabel     matlab.ui.control.Label
        xmaximumSlider          matlab.ui.control.Slider
        xmaximumSliderLabel     matlab.ui.control.Label
        xminimumSlider          matlab.ui.control.Slider
        xminimumSliderLabel     matlab.ui.control.Label
        ZastosujButton          matlab.ui.control.Button
        WzyLabel                matlab.ui.control.Label
        yEditField              matlab.ui.control.EditField
        yEditFieldLabel         matlab.ui.control.Label
        xEditField              matlab.ui.control.EditField
        xEditFieldLabel         matlab.ui.control.Label
        WywietlawzyButtonGroup  matlab.ui.container.ButtonGroup
        NieButton               matlab.ui.control.RadioButton
        TakButton               matlab.ui.control.RadioButton
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xmin = -5
        xmax = 5
        ymin = -10
        ymax = 10
        wezly_x
        wezly_y
        wyswietlacWezly = true
    end
    
    methods (Access = private)
        
        function [] = plotWykres(app)
            x = linspace(app.xmin, app.xmax, 1000);
            plot(app.UIAxes, x, wielomianInterpolacyjny(app.wezly_x, app.wezly_y, x), 'Color', 'r');
            axis(app.UIAxes, [app.xmin app.xmax app.ymin app.ymax]);
            if app.wyswietlacWezly
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.wezly_x, app.wezly_y, '.', 'MarkerSize', 18, 'Color', 'k');
                hold(app.UIAxes, 'off');
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: xminimumSlider
        function xminimumSliderValueChanged(app, event)
            app.xmin = event.Value;
            app.plotWykres();
        end

        % Value changing function: xmaximumSlider
        function xmaximumSliderValueChanging(app, event)
            app.xmax = event.Value;
            app.plotWykres();
        end

        % Value changing function: xminimumSlider
        function xminimumSliderValueChanging(app, event)
            app.xmin = event.Value;
            app.plotWykres();
        end

        % Selection changed function: WywietlawzyButtonGroup
        function WywietlawzyButtonGroupSelectionChanged(app, event)
            selectedButton = app.WywietlawzyButtonGroup.SelectedObject;
            if selectedButton == app.NieButton
                app.wyswietlacWezly = false;
            else
                app.wyswietlacWezly = true;
            end
            app.plotWykres();
        end

        % Button pushed function: ZastosujButton
        function ZastosujButtonPushed(app, event)
            app.plotWykres();
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.wezly_x = str2double(split(value, ' '));
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            app.wezly_y = str2double(split(value, ' '));
        end

        % Value changed function: xmaximumSlider
        function xmaximumSliderValueChanged(app, event)
            app.xmax = event.Value;
            app.plotWykres();
        end

        % Value changed function: ymaximumSlider
        function ymaximumSliderValueChanged(app, event)
            app.ymax = app.ymaximumSlider.Value;
            app.plotWykres();
        end

        % Value changing function: ymaximumSlider
        function ymaximumSliderValueChanging(app, event)
            app.ymax = event.Value;
            app.plotWykres();
        end

        % Value changed function: yminimumSlider
        function yminimumSliderValueChanged(app, event)
            app.ymin = app.yminimumSlider.Value;
            app.plotWykres();
        end

        % Value changing function: yminimumSlider
        function yminimumSliderValueChanging(app, event)
            app.ymin = event.Value;
            app.plotWykres();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wielomian interpolacyjny')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WywietlawzyButtonGroup = uibuttongroup(app.UIFigure);
            app.WywietlawzyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WywietlawzyButtonGroupSelectionChanged, true);
            app.WywietlawzyButtonGroup.Title = 'Wyświetlać węzły?';
            app.TakButton = uiradiobutton(app.WywietlawzyButtonGroup);
            app.TakButton.Text = 'Tak';
            app.TakButton.Value = true;
            app.NieButton = uiradiobutton(app.WywietlawzyButtonGroup);
            app.NieButton.Text = 'Nie';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.WzyLabel = uilabel(app.UIFigure);
            app.WzyLabel.Text = 'Węzły';
            app.ZastosujButton = uibutton(app.UIFigure, 'push');
            app.ZastosujButton.ButtonPushedFcn = createCallbackFcn(app, @ZastosujButtonPushed, true);
            app.ZastosujButton.Text = 'Zastosuj';
            app.xminimumSliderLabel = uilabel(app.UIFigure);
            app.xminimumSliderLabel.Text = 'x minimum';
            app.xminimumSlider = uislider(app.UIFigure);
            app.xminimumSlider.Limits = [-50 50];
            app.xminimumSlider.ValueChangedFcn = createCallbackFcn(app, @xminimumSliderValueChanged, true);
            app.xminimumSlider.ValueChangingFcn = createCallbackFcn(app, @xminimumSliderValueChanging, true);
            app.xminimumSlider.Value = -5;
            app.xmaximumSliderLabel = uilabel(app.UIFigure);
            app.xmaximumSliderLabel.Text = 'x maximum';
            app.xmaximumSlider = uislider(app.UIFigure);
            app.xmaximumSlider.Limits = [-50 50];
            app.xmaximumSlider.ValueChangedFcn = createCallbackFcn(app, @xmaximumSliderValueChanged, true);
            app.xmaximumSlider.ValueChangingFcn = createCallbackFcn(app, @xmaximumSliderValueChanging, true);
            app.xmaximumSlider.Value = 5;
            app.yminimumSliderLabel = uilabel(app.UIFigure);
            app.yminimumSliderLabel.Text = 'y minimum';
            app.yminimumSlider = uislider(app.UIFigure);
            app.yminimumSlider.Limits = [-50 50];
            app.yminimumSlider.ValueChangedFcn = createCallbackFcn(app, @yminimumSliderValueChanged, true);
            app.yminimumSlider.ValueChangingFcn = createCallbackFcn(app, @yminimumSliderValueChanging, true);
            app.yminimumSlider.Value = -10;
            app.ymaximumSliderLabel = uilabel(app.UIFigure);
            app.ymaximumSliderLabel.Text = 'y maximum';
            app.ymaximumSlider = uislider(app.UIFigure);
            app.ymaximumSlider.Limits = [-50 50];
            app.ymaximumSlider.ValueChangedFcn = createCallbackFcn(app, @ymaximumSliderValueChanged, true);
            app.ymaximumSlider.ValueChangingFcn = createCallbackFcn(app, @ymaximumSliderValueChanging, true);
            app.ymaximumSlider.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end